classdef BasicGUIIdea < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        InsertQueryHereLabel            matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SplashPageTab                   matlab.ui.container.Tab
        ElectronicMedicalRecordAccessPortalEMRAPLabel  matlab.ui.control.Label
        AccessLevelDropDownLabel        matlab.ui.control.Label
        AccessLevelDropDown             matlab.ui.control.DropDown
        SubmitTroubleTicketButton       matlab.ui.control.Button
        ViewHIPPAPrivacyInformationButton  matlab.ui.control.Button
        LoginButton                     matlab.ui.control.Button
        WhoAreYouEditFieldLabel         matlab.ui.control.Label
        WhoAreYouEditField              matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        PasswordEditField               matlab.ui.control.EditField
        AdminTab                        matlab.ui.container.Tab
        PersonnelActionsDropDownLabel   matlab.ui.control.Label
        PersonnelActionsDropDown        matlab.ui.control.DropDown
        EMRAPAdminLabel                 matlab.ui.control.Label
        SystemActionsDropDownLabel      matlab.ui.control.Label
        SystemActionsDropDown           matlab.ui.control.DropDown
        NameSSNEditFieldLabel           matlab.ui.control.Label
        NameSSNEditField                matlab.ui.control.EditField
        CircuitCriteriaEditFieldLabel   matlab.ui.control.Label
        CircuitCriteriaEditField        matlab.ui.control.EditField
        EnterButton                     matlab.ui.control.Button
        EnterButton_2                   matlab.ui.control.Button
        SwitchRoleButton                matlab.ui.control.Button
        RoleDropDownLabel               matlab.ui.control.Label
        RoleDropDown                    matlab.ui.control.DropDown
        CircuitLocationEditFieldLabel   matlab.ui.control.Label
        CircuitLocationEditField        matlab.ui.control.EditField
        AdminPersonnelTab               matlab.ui.container.Tab
        EMRAPAdminPersonnelResultsLabel  matlab.ui.control.Label
        UITable3                        matlab.ui.control.Table
        SearchAgainButton               matlab.ui.control.Button
        AdminCircuitTab                 matlab.ui.container.Tab
        EMRAPAdminCircuitResultsLabel   matlab.ui.control.Label
        UITable4                        matlab.ui.control.Table
        SearchAgainButton_2             matlab.ui.control.Button
        MedProfPatientSearchTab         matlab.ui.container.Tab
        PatientLastNameEditFieldLabel   matlab.ui.control.Label
        PatientLastNameEditField        matlab.ui.control.EditField
        DataofInterestEditFieldLabel    matlab.ui.control.Label
        DataofInterestEditField         matlab.ui.control.EditField
        GoGetitButton                   matlab.ui.control.Button
        EMRAPPatientSearchLabel         matlab.ui.control.Label
        ViewDropDownLabel               matlab.ui.control.Label
        ViewDropDown                    matlab.ui.control.DropDown
        PatientFirstNameEditField_2Label  matlab.ui.control.Label
        PatientFirstNameEditField_2     matlab.ui.control.EditField
        MedProfCircuitSearchTab         matlab.ui.container.Tab
        CircuitIDsEditFieldLabel        matlab.ui.control.Label
        CircuitIDsEditField             matlab.ui.control.EditField
        DataofInterestEditField_2Label  matlab.ui.control.Label
        DataofInterestEditField_2       matlab.ui.control.EditField
        GoGetitButton_2                 matlab.ui.control.Button
        EMRAPCircuitSearchLabel         matlab.ui.control.Label
        ViewDropDown_2Label             matlab.ui.control.Label
        ViewDropDown_2                  matlab.ui.control.DropDown
        MedProfPTComparisonViewTab      matlab.ui.container.Tab
        EMRAPPatientSearchResultsComparisonLabel  matlab.ui.control.Label
        UITable5                        matlab.ui.control.Table
        SearchAgainButton_3             matlab.ui.control.Button
        MedProfCircuitComparisonViewTab  matlab.ui.container.Tab
        EMRAPCircuitSearchResultsComparisonLabel  matlab.ui.control.Label
        UITable5_3                      matlab.ui.control.Table
        SearchAgainButton_6             matlab.ui.control.Button
        MedProfPTTimelineViewTab        matlab.ui.container.Tab
        EMRAPPatientSearchResultsTimelineLabel  matlab.ui.control.Label
        UITable5_2                      matlab.ui.control.Table
        SearchAgainButton_4             matlab.ui.control.Button
        TimelineUnitButton              matlab.ui.control.Button
        GraphViewButton                 matlab.ui.control.Button
        MedProfCircuitTimelineViewTab   matlab.ui.container.Tab
        EMRAPCircuitSearchResultsTimelineLabel  matlab.ui.control.Label
        UITable5_4                      matlab.ui.control.Table
        SearchAgainButton_7             matlab.ui.control.Button
        TimelineUnitButton_2            matlab.ui.control.Button
        GraphViewButton_2               matlab.ui.control.Button
        MedProfPTTimelineViewTab_2      matlab.ui.container.Tab
        EMRAPPatientSearchResultsTimelineLabel_2  matlab.ui.control.Label
        SearchAgainButton_5             matlab.ui.control.Button
        XAxisValueUnitButton            matlab.ui.control.Button
        TableViewButton                 matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        YAxisValueUnitButton            matlab.ui.control.Button
        MedProfCircuitTimelineViewTab_2  matlab.ui.container.Tab
        EMRAPCircuitSearchResultsTimelineLabel_2  matlab.ui.control.Label
        SearchAgainButton_8             matlab.ui.control.Button
        XAxisValueUnitButton_2          matlab.ui.control.Button
        TableViewButton_2               matlab.ui.control.Button
        UIAxes2_2                       matlab.ui.control.UIAxes
        YAxisValueUnitButton_2          matlab.ui.control.Button
        MedProfGeneralSearchTab         matlab.ui.container.Tab
        WhatareyouinterestedinlearningEditFieldLabel  matlab.ui.control.Label
        WhatareyouinterestedinlearningEditField  matlab.ui.control.EditField
        UITable2                        matlab.ui.control.Table
        SearchButton                    matlab.ui.control.Button
        EMRAPAnswersLabel               matlab.ui.control.Label
        ResultingQueryTextAreaLabel     matlab.ui.control.Label
        SQL_Result_String               matlab.ui.control.TextArea
        PatientViewTab                  matlab.ui.container.Tab
        PatientViewLabel                matlab.ui.control.Label
        WhoAreyouEditFieldLabel         matlab.ui.control.Label
        WhoAreyouEditField              matlab.ui.control.EditField
        NetworkMxTab                    matlab.ui.container.Tab
        SystemMaintenanceLabel          matlab.ui.control.Label
        FixStuffSliderLabel             matlab.ui.control.Label
        FixStuffSlider                  matlab.ui.control.Slider
        FixThisStuffButtonGroup         matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        Button2                         matlab.ui.control.RadioButton
        Button3                         matlab.ui.control.RadioButton
        Tree                            matlab.ui.container.Tree
        WhatsBrokeNode                  matlab.ui.container.TreeNode
        Node2                           matlab.ui.container.TreeNode
        Node3                           matlab.ui.container.TreeNode
        Node4                           matlab.ui.container.TreeNode
    end

    methods (Access = private)

        % Value changed function: 
        % WhatareyouinterestedinlearningEditField
        function RunClassificationElso(app, event)
            d= uiprogressdlg(app.UIFigure,'Title','Running Query','Indeterminate','on','Message','Running Query...');
            value = app.WhatareyouinterestedinlearningEditField.Value;
            [sqlquery,returnTable]=ClassificationElso(value);
            
            app.UITable2.Data=returnTable;
            app.UITable2.ColumnName=returnTable.Properties.VariableNames;
            
            app.SQL_Result_String.Value=sqlquery;
            if width(returnTable)==2 %create histogram
                histogram(app.UIAxes2_2,returnTable.(2));
                app.UIAxes2_2.YLabel.String='Number of Records';
                app.UIAxes2_2.XLabel.String=returnTable.Properties.VariableNames{2};
            end
            
            close(d);
            
        end

        % Button pushed function: ViewHIPPAPrivacyInformationButton
        function LaunchHipaaSite(app, event)
            web('https://www.hhs.gov/hipaa/index.html','-new','-browser');
        end

        % Button pushed function: LoginButton
        function LoginFunction(app, event)
            % make sure a user name and pwd has been entered
            
            if ~isempty(app.WhoAreYouEditField.Value) && ~isempty(app.PasswordEditField.Value)
                
                if strcmp(app.AccessLevelDropDown.Value,'Admin')
                    app.TabGroup.SelectedTab=app.AdminTab;
                elseif strcmp(app.AccessLevelDropDown.Value,'Medical Professional')
                    app.TabGroup.SelectedTab=app.MedProfGeneralSearchTab;
                elseif strcmp(app.AccessLevelDropDown.Value, 'Patient')
                    app.TabGroup.SelectedTab=app.PatientViewTab;
                else
                    %do nothing.
                    
                end
                
            else
                
                uialert(app.UIFigure,'Please enter a User Name and Password','Invalid Credentials');
                
            end
        end

        % Button pushed function: GoGetitButton
        function SearchEMR(app, event)
            d=uiprogressdlg(app.UIFigure,'Title','Running Query','Indeterminate','on','Message','Running Query...');
            try
            patient_data=patient_query_1(app.PatientLastNameEditField.Value,app.PatientFirstNameEditField_2.Value);
            
            
            app.UITable5_2.Data=patient_data;
            app.UITable5_2.ColumnName=patient_data.Properties.VariableNames;
            
            app.TabGroup.SelectedTab=app.MedProfPTTimelineViewTab;
           
            
            close(d);
            
            catch
                close(d);
                uialert(app.UIFigure,'No Data to return. Check spelling of Name','Invalid Query');
            end
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InsertQueryHereLabel = uilabel(app.UIFigure);
            app.InsertQueryHereLabel.Text = 'Insert Query Here';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SplashPageTab = uitab(app.TabGroup);
            app.SplashPageTab.Title = 'Splash Page';
            app.ElectronicMedicalRecordAccessPortalEMRAPLabel = uilabel(app.SplashPageTab);
            app.ElectronicMedicalRecordAccessPortalEMRAPLabel.Text = {'Electronic Medical Record Access Portal'; '(EMRAP)'; ''};
            app.AccessLevelDropDownLabel = uilabel(app.SplashPageTab);
            app.AccessLevelDropDownLabel.Text = 'Access Level';
            app.AccessLevelDropDown = uidropdown(app.SplashPageTab);
            app.AccessLevelDropDown.Items = {'Medical Professional', 'Patient', 'Admin', 'Mx', 'Special'};
            app.AccessLevelDropDown.Value = 'Medical Professional';
            app.SubmitTroubleTicketButton = uibutton(app.SplashPageTab, 'push');
            app.SubmitTroubleTicketButton.Text = 'Submit Trouble Ticket';
            app.ViewHIPPAPrivacyInformationButton = uibutton(app.SplashPageTab, 'push');
            app.ViewHIPPAPrivacyInformationButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchHipaaSite, true);
            app.ViewHIPPAPrivacyInformationButton.Text = 'View HIPPA Privacy Information';
            app.LoginButton = uibutton(app.SplashPageTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginFunction, true);
            app.LoginButton.Text = 'Login';
            app.WhoAreYouEditFieldLabel = uilabel(app.SplashPageTab);
            app.WhoAreYouEditFieldLabel.Text = 'Who Are You?';
            app.WhoAreYouEditField = uieditfield(app.SplashPageTab, 'text');
            app.PasswordEditFieldLabel = uilabel(app.SplashPageTab);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.SplashPageTab, 'text');
            app.AdminTab = uitab(app.TabGroup);
            app.AdminTab.Title = 'Admin';
            app.PersonnelActionsDropDownLabel = uilabel(app.AdminTab);
            app.PersonnelActionsDropDownLabel.Text = 'Personnel Actions';
            app.PersonnelActionsDropDown = uidropdown(app.AdminTab);
            app.PersonnelActionsDropDown.Items = {'Add User', 'Remove User', 'Modify User', 'Search Access', 'View User List', 'N/A'};
            app.PersonnelActionsDropDown.Value = 'Add User';
            app.EMRAPAdminLabel = uilabel(app.AdminTab);
            app.EMRAPAdminLabel.Text = 'EMRAP Admin';
            app.SystemActionsDropDownLabel = uilabel(app.AdminTab);
            app.SystemActionsDropDownLabel.Text = 'System Actions';
            app.SystemActionsDropDown = uidropdown(app.AdminTab);
            app.SystemActionsDropDown.Items = {'Add Circuit', 'Remove Circuit', 'Modify Circuit', 'N/A'};
            app.SystemActionsDropDown.Value = 'Add Circuit';
            app.NameSSNEditFieldLabel = uilabel(app.AdminTab);
            app.NameSSNEditFieldLabel.Text = 'Name/SSN';
            app.NameSSNEditField = uieditfield(app.AdminTab, 'text');
            app.CircuitCriteriaEditFieldLabel = uilabel(app.AdminTab);
            app.CircuitCriteriaEditFieldLabel.Text = 'Circuit Criteria';
            app.CircuitCriteriaEditField = uieditfield(app.AdminTab, 'text');
            app.EnterButton = uibutton(app.AdminTab, 'push');
            app.EnterButton.Text = 'Enter';
            app.EnterButton_2 = uibutton(app.AdminTab, 'push');
            app.EnterButton_2.Text = 'Enter';
            app.SwitchRoleButton = uibutton(app.AdminTab, 'push');
            app.SwitchRoleButton.Text = 'Switch Role';
            app.RoleDropDownLabel = uilabel(app.AdminTab);
            app.RoleDropDownLabel.Text = 'Role';
            app.RoleDropDown = uidropdown(app.AdminTab);
            app.RoleDropDown.Items = {'Medical Professional', 'Patient', 'Admin', 'Mx', 'Special'};
            app.RoleDropDown.Value = 'Medical Professional';
            app.CircuitLocationEditFieldLabel = uilabel(app.AdminTab);
            app.CircuitLocationEditFieldLabel.Text = 'Circuit Location';
            app.CircuitLocationEditField = uieditfield(app.AdminTab, 'text');
            app.AdminPersonnelTab = uitab(app.TabGroup);
            app.AdminPersonnelTab.Title = 'Admin Personnel';
            app.EMRAPAdminPersonnelResultsLabel = uilabel(app.AdminPersonnelTab);
            app.EMRAPAdminPersonnelResultsLabel.Text = 'EMRAP Admin Personnel Results';
            app.UITable3 = uitable(app.AdminPersonnelTab);
            app.UITable3.ColumnName = {'User'; 'Role'; 'Expiration'};
            app.UITable3.RowName = {};
            app.SearchAgainButton = uibutton(app.AdminPersonnelTab, 'push');
            app.SearchAgainButton.Text = 'Search Again';
            app.AdminCircuitTab = uitab(app.TabGroup);
            app.AdminCircuitTab.Title = 'Admin Circuit ';
            app.EMRAPAdminCircuitResultsLabel = uilabel(app.AdminCircuitTab);
            app.EMRAPAdminCircuitResultsLabel.Text = 'EMRAP Admin Circuit Results';
            app.UITable4 = uitable(app.AdminCircuitTab);
            app.UITable4.ColumnName = {'Circuit '; 'Previous Data'; 'Updated Data'};
            app.UITable4.RowName = {};
            app.SearchAgainButton_2 = uibutton(app.AdminCircuitTab, 'push');
            app.SearchAgainButton_2.Text = 'Search Again';
            app.MedProfPatientSearchTab = uitab(app.TabGroup);
            app.MedProfPatientSearchTab.Title = 'Med Prof Patient Search';
            app.PatientLastNameEditFieldLabel = uilabel(app.MedProfPatientSearchTab);
            app.PatientLastNameEditFieldLabel.Text = 'Patient Last Name';
            app.PatientLastNameEditField = uieditfield(app.MedProfPatientSearchTab, 'text');
            app.DataofInterestEditFieldLabel = uilabel(app.MedProfPatientSearchTab);
            app.DataofInterestEditFieldLabel.Text = 'Data of Interest';
            app.DataofInterestEditField = uieditfield(app.MedProfPatientSearchTab, 'text');
            app.GoGetitButton = uibutton(app.MedProfPatientSearchTab, 'push');
            app.GoGetitButton.ButtonPushedFcn = createCallbackFcn(app, @SearchEMR, true);
            app.GoGetitButton.Text = 'Go Get it!';
            app.EMRAPPatientSearchLabel = uilabel(app.MedProfPatientSearchTab);
            app.EMRAPPatientSearchLabel.Text = 'EMRAP Patient Search';
            app.ViewDropDownLabel = uilabel(app.MedProfPatientSearchTab);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.MedProfPatientSearchTab);
            app.ViewDropDown.Items = {'Comparison', 'Timeline'};
            app.ViewDropDown.Value = 'Comparison';
            app.PatientFirstNameEditField_2Label = uilabel(app.MedProfPatientSearchTab);
            app.PatientFirstNameEditField_2Label.Text = 'Patient First Name';
            app.PatientFirstNameEditField_2 = uieditfield(app.MedProfPatientSearchTab, 'text');
            app.MedProfCircuitSearchTab = uitab(app.TabGroup);
            app.MedProfCircuitSearchTab.Title = 'Med Prof Circuit Search';
            app.CircuitIDsEditFieldLabel = uilabel(app.MedProfCircuitSearchTab);
            app.CircuitIDsEditFieldLabel.Text = 'Circuit ID(s)';
            app.CircuitIDsEditField = uieditfield(app.MedProfCircuitSearchTab, 'text');
            app.DataofInterestEditField_2Label = uilabel(app.MedProfCircuitSearchTab);
            app.DataofInterestEditField_2Label.Text = 'Data of Interest';
            app.DataofInterestEditField_2 = uieditfield(app.MedProfCircuitSearchTab, 'text');
            app.GoGetitButton_2 = uibutton(app.MedProfCircuitSearchTab, 'push');
            app.GoGetitButton_2.Text = 'Go Get it!';
            app.EMRAPCircuitSearchLabel = uilabel(app.MedProfCircuitSearchTab);
            app.EMRAPCircuitSearchLabel.Text = 'EMRAP Circuit Search';
            app.ViewDropDown_2Label = uilabel(app.MedProfCircuitSearchTab);
            app.ViewDropDown_2Label.Text = 'View';
            app.ViewDropDown_2 = uidropdown(app.MedProfCircuitSearchTab);
            app.ViewDropDown_2.Items = {'Comparison', 'Timeline'};
            app.ViewDropDown_2.Value = 'Comparison';
            app.MedProfPTComparisonViewTab = uitab(app.TabGroup);
            app.MedProfPTComparisonViewTab.Title = 'Med Prof PT Comparison View';
            app.EMRAPPatientSearchResultsComparisonLabel = uilabel(app.MedProfPTComparisonViewTab);
            app.EMRAPPatientSearchResultsComparisonLabel.Text = {'EMRAP Patient Search Results'; '(Comparison)'};
            app.UITable5 = uitable(app.MedProfPTComparisonViewTab);
            app.UITable5.ColumnName = {'PT ID'; 'Pre-exist Cond'; 'Current Condition'; 'Outcome'};
            app.UITable5.RowName = {};
            app.SearchAgainButton_3 = uibutton(app.MedProfPTComparisonViewTab, 'push');
            app.SearchAgainButton_3.Text = 'Search Again';
            app.MedProfCircuitComparisonViewTab = uitab(app.TabGroup);
            app.MedProfCircuitComparisonViewTab.Title = 'Med Prof Circuit Comparison View';
            app.EMRAPCircuitSearchResultsComparisonLabel = uilabel(app.MedProfCircuitComparisonViewTab);
            app.EMRAPCircuitSearchResultsComparisonLabel.Text = {'EMRAP Circuit Search Results'; '(Comparison)'};
            app.UITable5_3 = uitable(app.MedProfCircuitComparisonViewTab);
            app.UITable5_3.ColumnName = {'PT ID'; 'Pre-exist Cond'; 'Current Condition'; 'Outcome'};
            app.UITable5_3.RowName = {};
            app.SearchAgainButton_6 = uibutton(app.MedProfCircuitComparisonViewTab, 'push');
            app.SearchAgainButton_6.Text = 'Search Again';
            app.MedProfPTTimelineViewTab = uitab(app.TabGroup);
            app.MedProfPTTimelineViewTab.Title = 'Med Prof PT Timeline View';
            app.EMRAPPatientSearchResultsTimelineLabel = uilabel(app.MedProfPTTimelineViewTab);
            app.EMRAPPatientSearchResultsTimelineLabel.Text = {'EMRAP Patient Search Results'; '(Timeline)'};
            app.UITable5_2 = uitable(app.MedProfPTTimelineViewTab);
            app.UITable5_2.ColumnName = {'PT ID'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '1'; '1'; '2'; '3'};
            app.UITable5_2.RowName = {};
            app.UITable5_2.ColumnEditable = [false false false false false false false false false true false false false];
            app.SearchAgainButton_4 = uibutton(app.MedProfPTTimelineViewTab, 'push');
            app.SearchAgainButton_4.Text = 'Search Again';
            app.TimelineUnitButton = uibutton(app.MedProfPTTimelineViewTab, 'push');
            app.TimelineUnitButton.Text = 'Timeline Unit';
            app.GraphViewButton = uibutton(app.MedProfPTTimelineViewTab, 'push');
            app.GraphViewButton.Text = 'Graph View';
            app.MedProfCircuitTimelineViewTab = uitab(app.TabGroup);
            app.MedProfCircuitTimelineViewTab.Title = 'Med Prof Circuit Timeline View';
            app.EMRAPCircuitSearchResultsTimelineLabel = uilabel(app.MedProfCircuitTimelineViewTab);
            app.EMRAPCircuitSearchResultsTimelineLabel.Text = {'EMRAP Circuit Search Results'; '(Timeline)'};
            app.UITable5_4 = uitable(app.MedProfCircuitTimelineViewTab);
            app.UITable5_4.ColumnName = {'PT ID'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '1'; '1'; '2'; '3'};
            app.UITable5_4.RowName = {};
            app.UITable5_4.ColumnEditable = [false false false false false false false false false true false false false];
            app.SearchAgainButton_7 = uibutton(app.MedProfCircuitTimelineViewTab, 'push');
            app.SearchAgainButton_7.Text = 'Search Again';
            app.TimelineUnitButton_2 = uibutton(app.MedProfCircuitTimelineViewTab, 'push');
            app.TimelineUnitButton_2.Text = 'Timeline Unit';
            app.GraphViewButton_2 = uibutton(app.MedProfCircuitTimelineViewTab, 'push');
            app.GraphViewButton_2.Text = 'Graph View';
            app.MedProfPTTimelineViewTab_2 = uitab(app.TabGroup);
            app.MedProfPTTimelineViewTab_2.Title = 'Med Prof PT Timeline View';
            app.EMRAPPatientSearchResultsTimelineLabel_2 = uilabel(app.MedProfPTTimelineViewTab_2);
            app.EMRAPPatientSearchResultsTimelineLabel_2.Text = {'EMRAP Patient Search Results'; '(Timeline)'};
            app.SearchAgainButton_5 = uibutton(app.MedProfPTTimelineViewTab_2, 'push');
            app.SearchAgainButton_5.Text = 'Search Again';
            app.XAxisValueUnitButton = uibutton(app.MedProfPTTimelineViewTab_2, 'push');
            app.XAxisValueUnitButton.Text = 'X-Axis Value Unit';
            app.TableViewButton = uibutton(app.MedProfPTTimelineViewTab_2, 'push');
            app.TableViewButton.Text = 'Table View';
            app.UIAxes2 = uiaxes(app.MedProfPTTimelineViewTab_2);
            title(app.UIAxes2, 'Patient Timeline')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.YAxisValueUnitButton = uibutton(app.MedProfPTTimelineViewTab_2, 'push');
            app.YAxisValueUnitButton.Text = 'Y-Axis Value Unit';
            app.MedProfCircuitTimelineViewTab_2 = uitab(app.TabGroup);
            app.MedProfCircuitTimelineViewTab_2.Title = 'Med Prof Circuit Timeline View';
            app.EMRAPCircuitSearchResultsTimelineLabel_2 = uilabel(app.MedProfCircuitTimelineViewTab_2);
            app.EMRAPCircuitSearchResultsTimelineLabel_2.Text = {'EMRAP Circuit Search Results'; '(Timeline)'};
            app.SearchAgainButton_8 = uibutton(app.MedProfCircuitTimelineViewTab_2, 'push');
            app.SearchAgainButton_8.Text = 'Search Again';
            app.XAxisValueUnitButton_2 = uibutton(app.MedProfCircuitTimelineViewTab_2, 'push');
            app.XAxisValueUnitButton_2.Text = 'X-Axis Value Unit';
            app.TableViewButton_2 = uibutton(app.MedProfCircuitTimelineViewTab_2, 'push');
            app.TableViewButton_2.Text = 'Table View';
            app.UIAxes2_2 = uiaxes(app.MedProfCircuitTimelineViewTab_2);
            title(app.UIAxes2_2, 'Circuit Timeline')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.YAxisValueUnitButton_2 = uibutton(app.MedProfCircuitTimelineViewTab_2, 'push');
            app.YAxisValueUnitButton_2.Text = 'Y-Axis Value Unit';
            app.MedProfGeneralSearchTab = uitab(app.TabGroup);
            app.MedProfGeneralSearchTab.Title = 'Med Prof General Search';
            app.WhatareyouinterestedinlearningEditFieldLabel = uilabel(app.MedProfGeneralSearchTab);
            app.WhatareyouinterestedinlearningEditFieldLabel.Text = 'What are you interested in learning?';
            app.WhatareyouinterestedinlearningEditField = uieditfield(app.MedProfGeneralSearchTab, 'text');
            app.WhatareyouinterestedinlearningEditField.ValueChangedFcn = createCallbackFcn(app, @RunClassificationElso, true);
            app.UITable2 = uitable(app.MedProfGeneralSearchTab);
            app.UITable2.ColumnName = {'Results'; ''};
            app.UITable2.RowName = {};
            app.SearchButton = uibutton(app.MedProfGeneralSearchTab, 'push');
            app.SearchButton.Text = 'Search';
            app.EMRAPAnswersLabel = uilabel(app.MedProfGeneralSearchTab);
            app.EMRAPAnswersLabel.Text = 'EMRAP Answers';
            app.ResultingQueryTextAreaLabel = uilabel(app.MedProfGeneralSearchTab);
            app.ResultingQueryTextAreaLabel.Text = 'Resulting Query';
            app.SQL_Result_String = uitextarea(app.MedProfGeneralSearchTab);
            app.PatientViewTab = uitab(app.TabGroup);
            app.PatientViewTab.Title = 'Patient View';
            app.PatientViewLabel = uilabel(app.PatientViewTab);
            app.PatientViewLabel.Text = 'Patient View';
            app.WhoAreyouEditFieldLabel = uilabel(app.PatientViewTab);
            app.WhoAreyouEditFieldLabel.Text = 'Who Are you?';
            app.WhoAreyouEditField = uieditfield(app.PatientViewTab, 'text');
            app.NetworkMxTab = uitab(app.TabGroup);
            app.NetworkMxTab.Title = 'Network Mx';
            app.SystemMaintenanceLabel = uilabel(app.NetworkMxTab);
            app.SystemMaintenanceLabel.Text = 'System Maintenance';
            app.FixStuffSliderLabel = uilabel(app.NetworkMxTab);
            app.FixStuffSliderLabel.Text = 'Fix Stuff';
            app.FixStuffSlider = uislider(app.NetworkMxTab);
            app.FixThisStuffButtonGroup = uibuttongroup(app.NetworkMxTab);
            app.FixThisStuffButtonGroup.Title = 'Fix This Stuff';
            app.Button = uiradiobutton(app.FixThisStuffButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.FixThisStuffButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.FixThisStuffButtonGroup);
            app.Button3.Text = 'Button3';
            app.Tree = uitree(app.NetworkMxTab);
            app.WhatsBrokeNode = uitreenode(app.Tree);
            app.WhatsBrokeNode.Text = 'What''s Broke?';
            app.Node2 = uitreenode(app.WhatsBrokeNode);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.WhatsBrokeNode);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.WhatsBrokeNode);
            app.Node4.Text = 'Node4';
        end
    end

    methods (Access = public)

        % Construct app
        function app = BasicGUIIdea

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end